classdef comp2 < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        InputImageLabel         matlab.ui.control.Label
        ThresholdValueLabel     matlab.ui.control.Label
        coif3Button             matlab.ui.control.Button
        bior13Button            matlab.ui.control.Button
        db2Button               matlab.ui.control.Button
        haarButton              matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        sym3Button              matlab.ui.control.Button
        ThresholdSlider         matlab.ui.control.Slider
        ThresholdSliderLabel    matlab.ui.control.Label
        SelectInputImageButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectInputImageButton
        function SelectInputImageButtonPushed(comp, event)
      global image1
          comp.bior13Button.Enable='on';
            comp.haarButton.Enable='on';
            comp.db2Button.Enable='on';
            comp.ThresholdSlider.Enable='on';
            comp.sym3Button.Enable='on';
            comp.ThresholdValueLabel.Enable='on';
            comp.coif3Button.Enable='on';
            comp.ThresholdSliderLabel.Enable='on';


            [filename, path] = uigetfile('.bmp', 'Select an image');
image_path = fullfile(path, filename);

% Read the original image
image1 = imread(image_path);
comp.Image.ImageSource=(image1)
        end

        % Callback function
        function ImageClicked(comp, event)
           
        end

        % Button pushed function: haarButton
        function haarButtonPushed(comp, event)
       
global image1


% Convert to grayscale if necessary
if size(image1, 3) == 3
    image1 = rgb2gray(image1);
end

% Display original image
figure;
subplot(1, 5, 1);
imshow(image1);
title('Original Image');

% Define wavelets
wavelets = {'haar'};
compressed_images = cell(1, numel(wavelets));
psnr_values = zeros(1, numel(wavelets));
mse_values = zeros(1, numel(wavelets));

for idx = 1:numel(wavelets)
    % Perform DWT
    [cA, cH, cV, cD] = dwt2(image1, wavelets{idx});
    
    % Thresholding and compressing
    threshold= comp.ThresholdSlider.Value
     % Adjust threshold as desired
    cH_thresholded = wthresh(double(cH), 's', threshold);
    
    % Perform IDWT to obtain compressed image
    compressed_image = idwt2(cA, cH_thresholded, cV, cD, wavelets{idx});
    compressed_images{idx} = uint8(compressed_image); % Ensure the result is uint8
    
    % Convert compressed image to the same class as the original image
    compressed_image = cast(compressed_image, class(image1));
    
    % Compute PSNR between the original and compressed images
    mse = immse(image1, compressed_image);
    max_pixel = double(max(image1(:)));
    psnr_value = 10 * log10(max_pixel^2 / mse); % Calculate PSNR in dB
    psnr_values(idx) = psnr_value;
    
    % Compute MSE between the original and compressed images
    mse_values(idx) = mse;
    
    % Display the horizontal detail coefficient
    subplot(3, numel(wavelets), idx + numel(wavelets));
    imshow(cH_thresholded, []);
    title(sprintf('Horizontal Detail Coefficient (%s)', wavelets{idx}));
    
    % Display the approximation coefficientx    ;
    subplot(3, numel(wavelets), idx + 2*numel(wavelets));
    imshow(cA, []);
    title(sprintf('Approximation Coefficient (%s)', wavelets{idx}));
end

% Display compressed images and PSNR/MSE values
for idx = 1:numel(wavelets)
    subplot(3, numel(wavelets), idx);
    imshow(compressed_images{idx});
    title(sprintf('Compressed Image (%s)\nPSNR: %.2f dB\nMSE: %.2f', ...
        wavelets{idx}, psnr_values(idx), mse_values(idx)));
end


        end

        % Button pushed function: db2Button
        function db2ButtonPushed(comp, event)
           
 global image1


% Convert to grayscale if necessary
if size(image1, 3) == 3
    image1 = rgb2gray(image1);
end

% Display original image
figure;
subplot(1, 5, 1);
imshow(image1);
title('Original Image');

% Define wavelets
wavelets = {'coif3'};
compressed_images = cell(1, numel(wavelets));
psnr_values = zeros(1, numel(wavelets));
mse_values = zeros(1, numel(wavelets));

for idx = 1:numel(wavelets)
    % Perform DWT
    [cA, cH, cV, cD] = dwt2(image1, wavelets{idx});
    
    % Thresholding and compressing
    threshold= comp.ThresholdSlider.Value
     % Adjust threshold as desired
    cH_thresholded = wthresh(double(cH), 's', threshold);
    
    % Perform IDWT to obtain compressed image
    compressed_image = idwt2(cA, cH_thresholded, cV, cD, wavelets{idx});
    compressed_images{idx} = uint8(compressed_image); % Ensure the result is uint8
    
    % Convert compressed image to the same class as the original image
    compressed_image = cast(compressed_image, class(image1));
    
    % Compute PSNR between the original and compressed images
    mse = immse(image1, compressed_image);
    max_pixel = double(max(image1(:)));
    psnr_value = 10 * log10(max_pixel^2 / mse); % Calculate PSNR in dB
    psnr_values(idx) = psnr_value;
    
    % Compute MSE between the original and compressed images
    mse_values(idx) = mse;
    
    % Display the horizontal detail coefficient
    subplot(3, numel(wavelets), idx + numel(wavelets));
    imshow(cH_thresholded, []);
    title(sprintf('Horizontal Detail Coefficient (%s)', wavelets{idx}));
    
    % Display the approximation coefficientx    ;
    subplot(3, numel(wavelets), idx + 2*numel(wavelets));
    imshow(cA, []);
    title(sprintf('Approximation Coefficient (%s)', wavelets{idx}));
end

% Display compressed images and PSNR/MSE values
for idx = 1:numel(wavelets)
    subplot(3, numel(wavelets), idx);
    imshow(compressed_images{idx});
    title(sprintf('Compressed Image (%s)\nPSNR: %.2f dB\nMSE: %.2f', ...
        wavelets{idx}, psnr_values(idx), mse_values(idx)));
end


        end

        % Callback function
        function SliderValueChanged(comp, event)
           
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(comp, event)
            changingValue = event.Value;
            
            % Get the current value of the slider
    sliderValue = comp.ThresholdSlider.Value;
    
    % Update the label to display the slider value
   comp.ThresholdValueLabel.Text = sprintf('Threshold Value: %.2f', sliderValue);
        end

        % Image clicked function: Image
        function ImageClicked2(comp, event)
            comp.bior13Button.Enable='off';
            comp.haarButton.Enable='off';
            comp.db2Button.Enable='off';
            comp.ThresholdSlider.Enable='off';
            comp.sym3Button.Enable='off';
            comp.ThresholdValueLabel.Enable='off';
            comp.coif3Button.Enable='off';
            comp.ThresholdSliderLabel.Enable='off';


        end

        % Button pushed function: coif3Button
        function coif3ButtonPushed(comp, event)
              global image1


% Convert to grayscale if necessary
if size(image1, 3) == 3
    image1 = rgb2gray(image1);
end

% Display original image
figure;
subplot(1, 5, 1);
imshow(image1);
title('Original Image');

% Define wavelets
wavelets = {'db2'};
compressed_images = cell(1, numel(wavelets));
psnr_values = zeros(1, numel(wavelets));
mse_values = zeros(1, numel(wavelets));

for idx = 1:numel(wavelets)
    % Perform DWT
    [cA, cH, cV, cD] = dwt2(image1, wavelets{idx});
    
    % Thresholding and compressing
    threshold= comp.ThresholdSlider.Value
     % Adjust threshold as desired
    cH_thresholded = wthresh(double(cH), 's', threshold);
    
    % Perform IDWT to obtain compressed image
    compressed_image = idwt2(cA, cH_thresholded, cV, cD, wavelets{idx});
    compressed_images{idx} = uint8(compressed_image); % Ensure the result is uint8
    
    % Convert compressed image to the same class as the original image
    compressed_image = cast(compressed_image, class(image1));
    
    % Compute PSNR between the original and compressed images
    mse = immse(image1, compressed_image);
    max_pixel = double(max(image1(:)));
    psnr_value = 10 * log10(max_pixel^2 / mse); % Calculate PSNR in dB
    psnr_values(idx) = psnr_value;
    
    % Compute MSE between the original and compressed images
    mse_values(idx) = mse;
    
    % Display the horizontal detail coefficient
    subplot(3, numel(wavelets), idx + numel(wavelets));
    imshow(cH_thresholded, []);
    title(sprintf('Horizontal Detail Coefficient (%s)', wavelets{idx}));
    
    % Display the approximation coefficientx    ;
    subplot(3, numel(wavelets), idx + 2*numel(wavelets));
    imshow(cA, []);
    title(sprintf('Approximation Coefficient (%s)', wavelets{idx}));
end

% Display compressed images and PSNR/MSE values
for idx = 1:numel(wavelets)
    subplot(3, numel(wavelets), idx);
    imshow(compressed_images{idx});
    title(sprintf('Compressed Image (%s)\nPSNR: %.2f dB\nMSE: %.2f', ...
        wavelets{idx}, psnr_values(idx), mse_values(idx)));
end


        end

        % Button pushed function: sym3Button
        function sym3ButtonPushed(comp, event)
             global image1


% Convert to grayscale if necessary
if size(image1, 3) == 3
    image1 = rgb2gray(image1);
end

% Display original image
figure;
subplot(1, 5, 1);
imshow(image1);
title('Original Image');

% Define wavelets
wavelets = {'sym3'};
compressed_images = cell(1, numel(wavelets));
psnr_values = zeros(1, numel(wavelets));
mse_values = zeros(1, numel(wavelets));

for idx = 1:numel(wavelets)
    % Perform DWT
    [cA, cH, cV, cD] = dwt2(image1, wavelets{idx});
    
    % Thresholding and compressing
    threshold= comp.ThresholdSlider.Value
     % Adjust threshold as desired
    cH_thresholded = wthresh(double(cH), 's', threshold);
    
    % Perform IDWT to obtain compressed image
    compressed_image = idwt2(cA, cH_thresholded, cV, cD, wavelets{idx});
    compressed_images{idx} = uint8(compressed_image); % Ensure the result is uint8
    
    % Convert compressed image to the same class as the original image
    compressed_image = cast(compressed_image, class(image1));
    
    % Compute PSNR between the original and compressed images
    mse = immse(image1, compressed_image);
    max_pixel = double(max(image1(:)));
    psnr_value = 10 * log10(max_pixel^2 / mse); % Calculate PSNR in dB
    psnr_values(idx) = psnr_value;
    
    % Compute MSE between the original and compressed images
    mse_values(idx) = mse;
    
    % Display the horizontal detail coefficient
    subplot(3, numel(wavelets), idx + numel(wavelets));
    imshow(cH_thresholded, []);
    title(sprintf('Horizontal Detail Coefficient (%s)', wavelets{idx}));
    
    % Display the approximation coefficientx    ;
    subplot(3, numel(wavelets), idx + 2*numel(wavelets));
    imshow(cA, []);
    title(sprintf('Approximation Coefficient (%s)', wavelets{idx}));
end

% Display compressed images and PSNR/MSE values
for idx = 1:numel(wavelets)
    subplot(3, numel(wavelets), idx);
    imshow(compressed_images{idx});
    title(sprintf('Compressed Image (%s)\nPSNR: %.2f dB\nMSE: %.2f', ...
        wavelets{idx}, psnr_values(idx), mse_values(idx)));
end


        end

        % Button pushed function: bior13Button
        function bior13ButtonPushed(comp, event)
             global image1


% Convert to grayscale if necessary
if size(image1, 3) == 3
    image1 = rgb2gray(image1);
end

% Display original image
figure;
subplot(1, 5, 1);
imshow(image1);
title('Original Image');

% Define wavelets
wavelets = {'bior1.3'};
compressed_images = cell(1, numel(wavelets));
psnr_values = zeros(1, numel(wavelets));
mse_values = zeros(1, numel(wavelets));

for idx = 1:numel(wavelets)
    % Perform DWT
    [cA, cH, cV, cD] = dwt2(image1, wavelets{idx});
    
    % Thresholding and compressing
    threshold= comp.ThresholdSlider.Value
     % Adjust threshold as desired
    cH_thresholded = wthresh(double(cH), 's', threshold);
    
    % Perform IDWT to obtain compressed image
    compressed_image = idwt2(cA, cH_thresholded, cV, cD, wavelets{idx});
    compressed_images{idx} = uint8(compressed_image); % Ensure the result is uint8
    
    % Convert compressed image to the same class as the original image
    compressed_image = cast(compressed_image, class(image1));
    
    % Compute PSNR between the original and compressed images
    mse = immse(image1, compressed_image);
    max_pixel = double(max(image1(:)));
    psnr_value = 10 * log10(max_pixel^2 / mse); % Calculate PSNR in dB
    psnr_values(idx) = psnr_value;
    
    % Compute MSE between the original and compressed images
    mse_values(idx) = mse;
    
    % Display the horizontal detail coefficient
    subplot(3, numel(wavelets), idx + numel(wavelets));
    imshow(cH_thresholded, []);
    title(sprintf('Horizontal Detail Coefficient (%s)', wavelets{idx}));
    
    % Display the approximation coefficientx    ;
    subplot(3, numel(wavelets), idx + 2*numel(wavelets));
    imshow(cA, []);
    title(sprintf('Approximation Coefficient (%s)', wavelets{idx}));
end

% Display compressed images and PSNR/MSE values
for idx = 1:numel(wavelets)
    subplot(3, numel(wavelets), idx);
    imshow(compressed_images{idx});
    title(sprintf('Compressed Image (%s)\nPSNR: %.2f dB\nMSE: %.2f', ...
        wavelets{idx}, psnr_values(idx), mse_values(idx)));
end


        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
           
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 422 505];
            comp.BackgroundColor = [0.6392 0.7647 0.8];
            comp.SelectInputImageButton = uibutton(comp, 'push');
            comp.SelectInputImageButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectInputImageButtonPushed, true);
            comp.SelectInputImageButton.Text = 'Select Input Image';
            comp.ThresholdSliderLabel = uilabel(comp);
            comp.ThresholdSliderLabel.Text = 'Threshold';
            comp.ThresholdSlider = uislider(comp);
            comp.ThresholdSlider.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @ThresholdSliderValueChanging, true);
            comp.sym3Button = uibutton(comp, 'push');
            comp.sym3Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @sym3ButtonPushed, true);
            comp.sym3Button.Text = 'sym3';
            comp.Image = uiimage(comp);
            comp.Image.ImageClickedFcn = matlab.apps.createCallbackFcn(comp, @ImageClicked2, true);
            comp.haarButton = uibutton(comp, 'push');
            comp.haarButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @haarButtonPushed, true);
            comp.haarButton.Text = 'haar';
            comp.db2Button = uibutton(comp, 'push');
            comp.db2Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @db2ButtonPushed, true);
            comp.db2Button.Text = 'db2';
            comp.bior13Button = uibutton(comp, 'push');
            comp.bior13Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @bior13ButtonPushed, true);
            comp.bior13Button.Text = 'bior1.3';
            comp.coif3Button = uibutton(comp, 'push');
            comp.coif3Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @coif3ButtonPushed, true);
            comp.coif3Button.Text = 'coif3';
            comp.ThresholdValueLabel = uilabel(comp);
            comp.ThresholdValueLabel.Text = 'Threshold Value';
            comp.InputImageLabel = uilabel(comp);
            comp.InputImageLabel.Text = 'Input Image';
        end
    end
end